classdef termproject_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ECIFramePanel               matlab.ui.container.Panel
        UIAxes2                     matlab.ui.control.UIAxes
        SkyPlotPanel                matlab.ui.container.Panel
        GroundTrackerPanel          matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        degLabel_4                  matlab.ui.control.Label
        degLabel_3                  matlab.ui.control.Label
        degLabel_2                  matlab.ui.control.Label
        degLabel                    matlab.ui.control.Label
        minelevationEditField       matlab.ui.control.NumericEditField
        minelevationEditFieldLabel  matlab.ui.control.Label
        initialEpoch                matlab.ui.control.Label
        finalEpoch                  matlab.ui.control.Label
        HEditField                  matlab.ui.control.NumericEditField
        HEditFieldLabel             matlab.ui.control.Label
        latEditField                matlab.ui.control.NumericEditField
        latEditFieldLabel           matlab.ui.control.Label
        IonEditField                matlab.ui.control.NumericEditField
        IonEditFieldLabel           matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        epochEditField              matlab.ui.control.EditField
        epochEditFieldLabel         matlab.ui.control.Label
        SYSDropDown                 matlab.ui.control.DropDown
        SYSDropDownLabel            matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
    end

    
    methods (Access = private)
        
        function results = func(app)
            load('nav.mat');

            if strcmp(app.SYSDropDown.Value, "GPS") == 1
                data = nav.GPS;
            elseif strcmp(app.SYSDropDown.Value, "QZSS") == 1
                data = nav.QZSS;
            else
                data = nav.BDS;
            end

            %initial
            omega = data.omega;
            i = data.i;
            OMEGA = data.OMEGA;
            e = data.e;
            a = data.a / 1000;                                          % km
            M0 = data.M0;
            t0 = juliandate(datetime(data.toc)) * 24 * 60;              % t0
            
            r0 = 6371;
            
            mu = 3.986004418e5;
            n = sqrt(mu / a^3) * 60;

            %input
            lon = app.IonEditField.Value;
            lat = app.latEditField.Value;
            h = app.HEditField.Value;
            el_mask = app.minelevationEditField.Value;
            timerange = app.Slider.Value;
            dt = 5;
            
            ECI_pos = [];
            ECEF_pos = [];
            lattrack = [];
            longtrack = [];

            for j = 0 : dt : timerange * 60
                t = t0 + j;
                M = M0 + n * j;
                time_scale = t / 60 / 24;
                time_scale = datetime(time_scale, 'convertfrom', 'juliandate');
    
                [yy mt dd] = ymd(time_scale);
                [hh mn ss] = hms(time_scale);
    
                time_scale = [yy mt dd hh mn ss];
                UTC0 = siderealTime(juliandate(datetime(time_scale))) * pi / 180;
                E1 = M;
                E2 = M + e * sin(M);

                % iteration
                while 1
                    if abs(E2 - E1) < 0.00001
                        break;
                    end
        
                    E1 = E2;
                    E2 = M + e * sin(E1); 
                end
                E = E2;

                v = atan2(sqrt(1 - e^2) * sin(E) / (1 - e * cos(E)), (cos(E) - e) / (1 - e * cos(E)));      %true anomaly
                PQW_pos = solveRangeInPerifocalFrame(a, e, v * 180 / pi);                                   % PQW_pos
                
                ECI_pos = [ ECI_pos PQW2ECI(omega * 180 / pi, i * 180 / pi, OMEGA * 180 / pi) * PQW_pos];
                ECEF_pos = [ ECEF_pos ECI2ECEF_DCM(time_scale) * PQW2ECI(omega * 180 / pi, i * 180 / pi, OMEGA * 180 / pi) * PQW_pos];

            end
            
            ENU_pos = ECEF2ENU_DCM(lon, lat) * ECEF_pos;
            
            Az = azimuth(ENU_pos');
            El = elevation(ENU_pos', el_mask);
            
            longtrack = [ longtrack atan2(ECEF_pos(2, :), ECEF_pos(1, :)) * 180 / pi];
            lattrack = [ lattrack atan2( ECEF_pos(3, :), sqrt(ECEF_pos(1, :).^2 + ECEF_pos(2, :).^2)) * 180 / pi];


            Az(find(Az < 0)) = 360 + Az(find(Az < 0));


            % jump 방지 groundtrack출력
            j = [];
            for i = 2:length(longtrack)
        
                if ((longtrack(i) > 170) && (longtrack(i-1) < -170)) || ((longtrack(i) < -170) && (longtrack(i-1) > 170))
                    j = [j,i];
                end
            end
            j = [j, length(longtrack)];

            
            plot(app.UIAxes, longtrack(1:(j(1)-1)), lattrack(1:(j(1)-1)), 'r');
            hold(app.UIAxes, "on");
            
            for i = 1:(length(j)-1)
                plot(app.UIAxes, longtrack(j(i):(j(i+1)-1)), lattrack(j(i):(j(i+1)-1)), 'r', 'HandleVisibility','off');
            end
            app.UIAxes.XLim = [-180 180];
            app.UIAxes.YLim = [-90 90];
%             background_image = imread('groundtrack.png');
%             image(app.UIAxes, background_image, 'XData', [-180 180], 'YData', [90 -90]);
%             set(app.UIAxes,'YDir', 'normal');
            

            hold(app.UIAxes,"off");

            skyplot(app.SkyPlotPanel,Az, El, "MarkerSizeData", 6);
            
            plot3(app.UIAxes2, ECI_pos(1, :), ECI_pos(2, :), ECI_pos(3, :));
            
            drawnow;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)


            % data : a, e, i, omega, M0, toc, OMEGA (at t = 0)
            % from data to calculate satteite position, velocity
                        
            %PQR -> XYZ
            cla(app.UIAxes);
            func(app);
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            % epoch field change also

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 24];
            app.Slider.MajorTicks = [0 24];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.SYSDropDownLabel = uilabel(app.UIFigure);
            app.SYSDropDownLabel.Text = 'SYS';
            app.SYSDropDown = uidropdown(app.UIFigure);
            app.SYSDropDown.Items = {'GPS', 'QZSS', 'BDS'};
            app.SYSDropDown.Value = 'GPS';
            app.epochEditFieldLabel = uilabel(app.UIFigure);
            app.epochEditFieldLabel.Text = 'epoch';
            app.epochEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.IonEditFieldLabel = uilabel(app.UIFigure);
            app.IonEditFieldLabel.Text = 'Ion';
            app.IonEditField = uieditfield(app.UIFigure, 'numeric');
            app.IonEditField.Value = 127;
            app.latEditFieldLabel = uilabel(app.UIFigure);
            app.latEditFieldLabel.Text = 'lat';
            app.latEditField = uieditfield(app.UIFigure, 'numeric');
            app.latEditField.Value = 37;
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEditField.Value = 1;
            app.finalEpoch = uilabel(app.UIFigure);
            app.finalEpoch.Text = ' ';
            app.initialEpoch = uilabel(app.UIFigure);
            app.initialEpoch.Text = ' ';
            app.minelevationEditFieldLabel = uilabel(app.UIFigure);
            app.minelevationEditFieldLabel.Text = 'min elevation';
            app.minelevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = 'deg';
            app.degLabel_3 = uilabel(app.UIFigure);
            app.degLabel_3.Text = 'deg';
            app.degLabel_4 = uilabel(app.UIFigure);
            app.degLabel_4.Text = 'deg';
            app.GroundTrackerPanel = uipanel(app.UIFigure);
            app.GroundTrackerPanel.Title = 'Ground Tracker';
            app.UIAxes = uiaxes(app.GroundTrackerPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SkyPlotPanel = uipanel(app.UIFigure);
            app.SkyPlotPanel.Title = 'SkyPlot';
            app.ECIFramePanel = uipanel(app.UIFigure);
            app.ECIFramePanel.Title = 'ECI Frame';
            app.UIAxes2 = uiaxes(app.ECIFramePanel);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = termproject_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end